classdef SIMULATION_TYPE_MODULE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIMULATION_TYPE_MODULEUIFigure  matlab.ui.Figure
        PERIODICButton                  matlab.ui.control.Button
        NONPERIODICButton               matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PERIODICButton
        function PERIODICButtonPushed(app, event)
            cd('Periodic'); %changing folder
            MODULE_periodic(); %running code for periodic simulations
            cd ..;
        end

        % Button pushed function: NONPERIODICButton
        function NONPERIODICButtonPushed(app, event)
            cd('Non-Periodic');
            ops = ["Load Existing Results";"Run New Simulation"];
            [idx,~] = listdlg('PromptString','Select Operation',...
                'ListString',ops,'ListSize',[200 50],'SelectionMode','single');
            if idx == 1 %loading existing data-set
                cd('Results')
                data = load('results.mat');
                WEATHER_output = data.WEATHER_output;
                MODULE_output = data.MODULE_output;
                TOOLBOX_input = data.TOOLBOX_input; 
                assignin('base','WEATHER_output',WEATHER_output);
                assignin('base','MODULE_output',MODULE_output);
                assignin('base','TOOLBOX_input',TOOLBOX_input);
                cd ..; %returning to main folder
            else
                MODULE_nonperiodic; %running the code for new simulation
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SIMULATION_TYPE_MODULEUIFigure = uifigure('Visible', 'off');
            app.SIMULATION_TYPE_MODULEUIFigure.Name = 'SIMULATION_TYPE_MODULE';
            app.PERIODICButton = uibutton(app.SIMULATION_TYPE_MODULEUIFigure, 'push');
            app.PERIODICButton.ButtonPushedFcn = createCallbackFcn(app, @PERIODICButtonPushed, true);
            app.PERIODICButton.Text = 'PERIODIC';
            app.NONPERIODICButton = uibutton(app.SIMULATION_TYPE_MODULEUIFigure, 'push');
            app.NONPERIODICButton.ButtonPushedFcn = createCallbackFcn(app, @NONPERIODICButtonPushed, true);
            app.NONPERIODICButton.Text = 'NON-PERIODIC';
            % Show the figure after all components are created
            app.SIMULATION_TYPE_MODULEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIMULATION_TYPE_MODULE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIMULATION_TYPE_MODULEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIMULATION_TYPE_MODULEUIFigure)
        end
    end
end