classdef GUI_drawing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveButton              matlab.ui.control.Button
        Tri_Material            matlab.ui.control.DropDown
        Rec_Material            matlab.ui.control.DropDown
        Tri_scattering          matlab.ui.control.CheckBox
        ScatteringLabel_2       matlab.ui.control.Label
        Rec_scattering          matlab.ui.control.CheckBox
        ScatteringLabel         matlab.ui.control.Label
        DeletelastobjectButton  matlab.ui.control.Button
        MaterialLabel_2         matlab.ui.control.Label
        MaterialLabel           matlab.ui.control.Label
        Tri_point3_z            matlab.ui.control.NumericEditField
        Tri_point3_y            matlab.ui.control.NumericEditField
        Tri_point3_x            matlab.ui.control.NumericEditField
        Tri_point2_z            matlab.ui.control.NumericEditField
        Tri_point2_y            matlab.ui.control.NumericEditField
        Tri_point2_x            matlab.ui.control.NumericEditField
        Tri_point1_z            matlab.ui.control.NumericEditField
        Tri_point1_y            matlab.ui.control.NumericEditField
        Tri_point1_x            matlab.ui.control.NumericEditField
        Rec_point4_z            matlab.ui.control.NumericEditField
        Rec_point4_y            matlab.ui.control.NumericEditField
        Rec_point4_x            matlab.ui.control.NumericEditField
        Rec_point3_z            matlab.ui.control.NumericEditField
        Rec_point3_y            matlab.ui.control.NumericEditField
        Rec_point3_x            matlab.ui.control.NumericEditField
        Rec_point2_z            matlab.ui.control.NumericEditField
        Rec_point2_y            matlab.ui.control.NumericEditField
        Rec_point2_x            matlab.ui.control.NumericEditField
        Rec_point1_z            matlab.ui.control.NumericEditField
        Rec_point1_y            matlab.ui.control.NumericEditField
        Rec_point1_x            matlab.ui.control.NumericEditField
        FinishButton            matlab.ui.control.Button
        Point3Label_2           matlab.ui.control.Label
        Point2Label_2           matlab.ui.control.Label
        Point4Label             matlab.ui.control.Label
        Point3Label             matlab.ui.control.Label
        Point2Label             matlab.ui.control.Label
        Point1Label_2           matlab.ui.control.Label
        Point1Label             matlab.ui.control.Label
        zLabel_2                matlab.ui.control.Label
        yLabel_2                matlab.ui.control.Label
        xLabel_2                matlab.ui.control.Label
        zLabel                  matlab.ui.control.Label
        yLabel                  matlab.ui.control.Label
        xLabel                  matlab.ui.control.Label
        DrawtriangleButton      matlab.ui.control.Button
        DrawrectangleButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        V % The list of all vertices
        F % The list of all faces
        materials % The materials of all faces
        fig % The figure which will be used to plot in
        rgb % The colors of the faces in the figure
        opa % The opacity of the faces in the figure
        Albedo %The albedo of all faces
        Scattering %Indication whether the reflection is scattered or direct
        last_obj %The last object that is drawn (1 is triangle, 2 is rectangle)
        path
    end
    
    methods (Access = private)
        
        function fig_out = draw_setting(app,V,F,fig,rgb,opa)
            figure(fig)
            clf(fig)
            hold on
            p = patch('Faces',F,'Vertices',V(:,1:3));
            set(p,'FaceVertexCData',rgb,'CDataMapping','scaled',...
                'FaceColor','flat','FaceVertexAlphaData',opa,...
                'AlphaDataMapping','none','FaceAlpha','flat')
            view(30,30)
            xlabel('X')
            ylabel('Y')
            zlabel('Z')
            xlim([-10,10])
            ylim([-10,10])
            zlim([0, 10])
            fig_out = fig;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, V_in, F_in, materials_in, fig_in, rgb_in, opa_in, Albedo_in, Scattering_in)
            app.V = V_in;
            app.F = F_in;
            app.materials = materials_in;
            app.fig = fig_in;
            app.rgb = rgb_in;
            app.opa = opa_in;
            app.Albedo = Albedo_in;
            app.Scattering = Scattering_in;
            
            app.path = (fullfile('..','BackwardTracer','SpectralReflectivityLibrary'));
            addpath(genpath(app.path));
        end

        % Button pushed function: DrawrectangleButton
        function DrawrectangleButtonPushed(app, event)
            [~,~,data_folder] = get_folder_structure;
            path_libarty = fullfile(data_folder,'Material Library','SpectralReflectivityLibrary');
            
            material_new = app.Rec_Material.Value;
            [ref,mat_found] = getMaterialReflectivity({material_new},path_libarty);
            opa_new = 1-ref;
            if ~mat_found
                disp('Material is not found, rectangle is not added to drawing')
            else
                
                size_V = size(app.V,1);
                
                %Get all coordinates from the textfields
                V1_new_x = app.Rec_point1_x.Value;
                V1_new_y = app.Rec_point1_y.Value;
                V1_new_z = app.Rec_point1_z.Value;
                V2_new_x = app.Rec_point2_x.Value;
                V2_new_y = app.Rec_point2_y.Value;
                V2_new_z = app.Rec_point2_z.Value;
                V3_new_x = app.Rec_point3_x.Value;
                V3_new_y = app.Rec_point3_y.Value;
                V3_new_z = app.Rec_point3_z.Value;
                V4_new_x = app.Rec_point4_x.Value;
                V4_new_y = app.Rec_point4_y.Value;
                V4_new_z = app.Rec_point4_z.Value;
                point1 = [V1_new_x,V1_new_y,V1_new_z];
                point2 = [V2_new_x,V2_new_y,V2_new_z];
                point3 = [V3_new_x,V3_new_y,V3_new_z];
                point4 = [V4_new_x,V4_new_y,V4_new_z];
                
                app.V = [app.V;point1;point2;point3;point4];
                
                %Calculate which point is the furthest from point 1 (this point
                %will be used to create two triangles
                dis1_2 = sqrt((V1_new_x-V2_new_x)^2+(V1_new_y-V2_new_y)^2+(V1_new_z-V2_new_z)^2);
                dis1_3 = sqrt((V1_new_x-V3_new_x)^2+(V1_new_y-V3_new_y)^2+(V1_new_z-V3_new_z)^2);
                dis1_4 = sqrt((V1_new_x-V4_new_x)^2+(V1_new_y-V4_new_y)^2+(V1_new_z-V4_new_z)^2);
                [~,ind_max] = max([0,dis1_2,dis1_3,dis1_4]); %0 is added such that the correct point is selected.
                
                if ind_max == 2
                    app.F = [app.F;size_V+1,size_V+2,size_V+3]; %Add the triangle with vertix 1,2,3 (plus the current size of V)
                    app.F = [app.F;size_V+1,size_V+2,size_V+4]; %Add the triangle with vertix 1,2,4 (plus the current size of V)
                    
                elseif ind_max == 3
                    app.F = [app.F;size_V+1,size_V+2,size_V+3]; %Add the triangle with vertix 1,2,3 (plus the current size of V)
                    app.F = [app.F;size_V+1,size_V+3,size_V+4]; %Add the triangle with vertix 1,3,4 (plus the current size of V)
                    
                elseif ind_max == 4
                    app.F = [app.F;size_V+1,size_V+2,size_V+4]; %Add the triangle with vertix 1,2,4 (plus the current size of V)
                    app.F = [app.F;size_V+1,size_V+3,size_V+4]; %Add the triangle with vertix 1,3,4 (plus the current size of V)
                    
                end
                
                app.materials = [app.materials,{material_new},{material_new}]; %Add new materials to the list
                app.rgb = [app.rgb;[0,0,0];[0,0,0]]; %Add colors to the list
                app.opa = [app.opa;opa_new;opa_new]; %Add new opacity to the list
                app.fig = draw_setting(app,app.V,app.F,app.fig,app.rgb,app.opa);
                
                Scattering = app.Tri_scattering.Value;
                app.Scattering = [app.Scattering;Scattering;Scattering];
                app.Albedo = [app.Albedo;ref;ref];
                
                app.last_obj = 2; %set last object to rectangle
            end
            
        end

        % Button pushed function: DrawtriangleButton
        function DrawtriangleButtonPushed(app, event)
            [~,~,data_folder] = get_folder_structure;
            path_libarty = fullfile(data_folder,'Material Library','SpectralReflectivityLibrary');
            
            material_new = app.Rec_Material.Value;
            [ref,mat_found] = getMaterialReflectivity({material_new},path_libarty);
            opa_new = 1-ref;
            if ~mat_found
                disp('Material is not found, rectangle is not added to drawing')
            else
                
                size_V = size(app.V,1);
                
                %Get all coordinates from the textfields
                V1_new_x = app.Tri_point1_x.Value;
                V1_new_y = app.Tri_point1_y.Value;
                V1_new_z = app.Tri_point1_z.Value;
                V2_new_x = app.Tri_point2_x.Value;
                V2_new_y = app.Tri_point2_y.Value;
                V2_new_z = app.Tri_point2_z.Value;
                V3_new_x = app.Tri_point3_x.Value;
                V3_new_y = app.Tri_point3_y.Value;
                V3_new_z = app.Tri_point3_z.Value;
                point1 = [V1_new_x,V1_new_y,V1_new_z];
                point2 = [V2_new_x,V2_new_y,V2_new_z];
                point3 = [V3_new_x,V3_new_y,V3_new_z];
                
                app.V = [app.V;point1;point2;point3];
                
              
                app.F = [app.F;size_V+1,size_V+2,size_V+3]; %Add the triangle with vertix 1,2,3 (plus the current size of V)
                               
                app.materials = [app.materials,{material_new}]; %Add new materials to the list
                app.rgb = [app.rgb;[0,0,0]]; %Add colors to the list
                app.opa = [app.opa;opa_new]; %Add new opacity to the list
                app.fig = draw_setting(app,app.V,app.F,app.fig,app.rgb,app.opa);
                
                Scattering = app.Tri_scattering.Value;
                app.Scattering = [app.Scattering;Scattering];
                app.Albedo = [app.Albedo;ref];
                
                app.last_obj = 1; %set last object to triangle
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            assignin('base','V',app.V)
            assignin('base','F',app.F)
            assignin('base','materials',app.materials)
            assignin('base','opa',app.opa)
            assignin('base','rgb',app.rgb)
            assignin('base','Albedo',app.Albedo)
            assignin('base','Scattering',app.Scattering)
            closereq();
        end

        % Button pushed function: DeletelastobjectButton
        function DeletelastobjectButtonPushed(app, event)
            size_V = size(app.V,1);
            if size_V > 4
                if app.last_obj == 1 %Last object was a triangle
                    app.V = app.V(1:end-3,:);
                    app.F = app.F(1:end-1,:);
                    app.rgb = app.rgb(1:end-1,:);
                    app.opa = app.opa(1:end-1,:);
                elseif app.last_obj ==2 %Last object was rectangle
                    app.V = app.V(1:end-4,:);
                    app.F = app.F(1:end-2,:);  
                    app.rgb = app.rgb(1:end-2,:);
                    app.opa = app.opa(1:end-2,:);                  
                end
                app.fig = draw_setting(app,app.V,app.F,app.fig,app.rgb,app.opa);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = input('What should the name be?','s');
            filename = append(filename,'.mat');

            [src_folder,~,~] = get_folder_structure;
            filename_save = fullfile(src_folder,'2_MODULE','Non-Periodic','Environments',filename);
            V = app.V;
            F = app.F;
            materials = app.materials;
            opa = app.opa;
            rgb = app.rgb;
            Albedo = app.Albedo;
            Scattering = app.Scattering;
            save(filename_save,'V','F','materials','opa','rgb','Albedo','Scattering')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrawrectangleButton = uibutton(app.UIFigure, 'push');
            app.DrawrectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangleButtonPushed, true);
            app.DrawrectangleButton.Text = 'Draw rectangle';
            app.DrawtriangleButton = uibutton(app.UIFigure, 'push');
            app.DrawtriangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawtriangleButtonPushed, true);
            app.DrawtriangleButton.Text = 'Draw triangle';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x';
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y';
            app.zLabel_2 = uilabel(app.UIFigure);
            app.zLabel_2.Text = 'z';
            app.Point1Label = uilabel(app.UIFigure);
            app.Point1Label.Text = 'Point 1';
            app.Point1Label_2 = uilabel(app.UIFigure);
            app.Point1Label_2.Text = 'Point 1';
            app.Point2Label = uilabel(app.UIFigure);
            app.Point2Label.Text = 'Point 2';
            app.Point3Label = uilabel(app.UIFigure);
            app.Point3Label.Text = 'Point 3';
            app.Point4Label = uilabel(app.UIFigure);
            app.Point4Label.Text = 'Point 4';
            app.Point2Label_2 = uilabel(app.UIFigure);
            app.Point2Label_2.Text = 'Point 2';
            app.Point3Label_2 = uilabel(app.UIFigure);
            app.Point3Label_2.Text = 'Point 3';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Rec_point1_x = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point1_x.Tag = 'Rec_point1_x';
            app.Rec_point1_y = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point1_y.Tag = 'Rec_point1_x';
            app.Rec_point1_z = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point1_z.Tag = 'Rec_point1_x';
            app.Rec_point2_x = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point2_x.Tag = 'Rec_point1_x';
            app.Rec_point2_y = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point2_y.Tag = 'Rec_point1_x';
            app.Rec_point2_z = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point2_z.Tag = 'Rec_point1_x';
            app.Rec_point3_x = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point3_x.Tag = 'Rec_point1_x';
            app.Rec_point3_y = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point3_y.Tag = 'Rec_point1_x';
            app.Rec_point3_z = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point3_z.Tag = 'Rec_point1_x';
            app.Rec_point4_x = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point4_x.Tag = 'Rec_point1_x';
            app.Rec_point4_y = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point4_y.Tag = 'Rec_point1_x';
            app.Rec_point4_z = uieditfield(app.UIFigure, 'numeric');
            app.Rec_point4_z.Tag = 'Rec_point1_x';
            app.Tri_point1_x = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point1_x.Tag = 'Rec_point1_x';
            app.Tri_point1_y = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point1_y.Tag = 'Rec_point1_x';
            app.Tri_point1_z = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point1_z.Tag = 'Rec_point1_x';
            app.Tri_point2_x = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point2_x.Tag = 'Rec_point1_x';
            app.Tri_point2_y = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point2_y.Tag = 'Rec_point1_x';
            app.Tri_point2_z = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point2_z.Tag = 'Rec_point1_x';
            app.Tri_point3_x = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point3_x.Tag = 'Rec_point1_x';
            app.Tri_point3_y = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point3_y.Tag = 'Rec_point1_x';
            app.Tri_point3_z = uieditfield(app.UIFigure, 'numeric');
            app.Tri_point3_z.Tag = 'Rec_point1_x';
            app.MaterialLabel = uilabel(app.UIFigure);
            app.MaterialLabel.Text = 'Material';
            app.MaterialLabel_2 = uilabel(app.UIFigure);
            app.MaterialLabel_2.Text = 'Material';
            app.DeletelastobjectButton = uibutton(app.UIFigure, 'push');
            app.DeletelastobjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastobjectButtonPushed, true);
            app.DeletelastobjectButton.Text = 'Delete last object';
            app.ScatteringLabel = uilabel(app.UIFigure);
            app.ScatteringLabel.Text = 'Scattering';
            app.Rec_scattering = uicheckbox(app.UIFigure);
            app.Rec_scattering.Text = '';
            app.Rec_scattering.Value = true;
            app.ScatteringLabel_2 = uilabel(app.UIFigure);
            app.ScatteringLabel_2.Text = 'Scattering';
            app.Tri_scattering = uicheckbox(app.UIFigure);
            app.Tri_scattering.Text = '';
            app.Tri_scattering.Value = true;
            app.Rec_Material = uidropdown(app.UIFigure);
            app.Rec_Material.Items = {'solar cell', 'camera', 'mirror', 'glass', 'white glass', 'white paint', 'blue paint', 'trees', 'grass', 'concrete', 'pebbles', 'black', 'aluminum', 'ground', 'new black', 'old black', 'cable holder', 'default'};
            app.Rec_Material.Value = 'concrete';
            app.Tri_Material = uidropdown(app.UIFigure);
            app.Tri_Material.Items = {'solar cell', 'camera', 'mirror', 'glass', 'white glass', 'white paint', 'blue paint', 'trees', 'grass', 'concrete', 'pebbles', 'black', 'aluminum', 'ground', 'new black', 'old black', 'cable holder', 'default'};
            app.Tri_Material.Value = 'concrete';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save environment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_drawing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end